classdef Digitizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DigitizerUIFigure         matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenImageMenu             matlab.ui.container.Menu
        SaveDataMenu              matlab.ui.container.Menu
        UIAxes                    matlab.ui.control.UIAxes
        SelectanimagefileLabel    matlab.ui.control.Label
        ControlPanel              matlab.ui.container.Panel
        AddPointsButton           matlab.ui.control.StateButton
        RemovePointsButton        matlab.ui.control.StateButton
        AddSeriesButton           matlab.ui.control.Button
        RemoveSeriesButton        matlab.ui.control.Button
        ChooseAnchorPointsButton  matlab.ui.control.StateButton
        logCheckBoxY              matlab.ui.control.CheckBox
        logCheckBoxX              matlab.ui.control.CheckBox
        X1EditFieldLabel          matlab.ui.control.Label
        X1EditField               matlab.ui.control.NumericEditField
        X2EditFieldLabel          matlab.ui.control.Label
        X2EditField               matlab.ui.control.NumericEditField
        Y1EditFieldLabel          matlab.ui.control.Label
        Y1EditField               matlab.ui.control.NumericEditField
        Y2EditFieldLabel          matlab.ui.control.Label
        Y2EditField               matlab.ui.control.NumericEditField
        xButton                   matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        DataSeriesListBoxLabel    matlab.ui.control.Label
        DataSeriesListBox         matlab.ui.control.ListBox
        EditButton                matlab.ui.control.Button
        ZoomAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        im = []; % image matrix
        anchorPoints = nan(1,4); % anchor points | Xstart Xend Ystart Yend
        anchorCounter = 0;
        inputStatus = 0; % 0 idle, 1 anchor, 2 data
        selectedSeries = 0;
        selectedDataPoint = 0;
        numberOfSeries = 0;
        data = {[]};
        dataCounter = {0};
        imHandle = [];
        coordinateFrame = {};
        realData = {[]};
        seriesName = {};
        webWindowHandle = [];
        x1 = []; x2 = []; y1 =[]; y2 = [];
        mainGUIPosition = [];
    end
    
    
    methods (Access = private)
        
        function pos = ImButtonDownFcn(app,event)
            pos = event.IntersectionPoint(1:2) + [2 2];
            switch app.inputStatus
                case 0
                    
                case 1 % Anchoring
                    app.anchorCounter = app.anchorCounter + 1;
                    if app.anchorCounter > 2
                        app.anchorCounter = 1;                
                    end
                    app.anchorPoints(app.selectedSeries,app.anchorCounter + [0 2]) = pos;

                case 2 % Data extraction
                    app.dataCounter{app.selectedSeries} = app.dataCounter{app.selectedSeries} + 1;
                    tempData = [app.data{app.selectedSeries}; pos];
                    sortedData = sortrows(tempData,1,'ascend');
                    app.selectedDataPoint = find(sortedData(:,1)==pos(1) & sortedData(:,2)==pos(2));
                    app.data{app.selectedSeries} = sortedData;
                    
                otherwise
                     % do nothing
            end
            
            app.UpdateData;
            app.PlotUpdate;
            
        end
    
        function UpdateData(app)
            try
                coordinateFrameX = app.coordinateFrame{app.selectedSeries}{1};
                coordinateFrameY = app.coordinateFrame{app.selectedSeries}{2};
                
                % convert selected points to real data
                app.realData{app.selectedSeries} = [coordinateFrameX(app.data{app.selectedSeries}(:,1)) ,...
                    coordinateFrameY(app.data{app.selectedSeries}(:,2))];
                app.UITable.Data = app.realData{app.selectedSeries};
            catch
            end
        end
    
        function PlotUpdate(app)
            
            % delete all children
            delete(findobj(app.UIAxes,'Type','line'));
            cols = lines(100);
            hold(app.UIAxes,'on');
            
            try
                iSeries = app.selectedSeries;
                plot(app.UIAxes, app.anchorPoints(iSeries,1:2),app.anchorPoints(iSeries,3:4),...
                    '+','Color',[1 .6 0],'MarkerSize',12,'LineWidth',3);
                
                % existing symbols sometimes prevents user to click on overlapping data points. 
                % so we only display the releveant series
                if app.inputStatus == 2
                    seriesToPlot = app.selectedSeries;
                elseif app.inputStatus == 1
                    seriesToPlot = [];
                else
                    seriesToPlot = 1:app.numberOfSeries;
                end
               
                for i = seriesToPlot
                    try
                        msize = 5;
                        if i == app.selectedSeries
                            msize = 10;
                        end
                        plot(app.UIAxes, app.data{i}(:,1),app.data{i}(:,2),...
                            'o','Color',cols(i,:),'MarkerFaceColor',cols(i,:),...
                            'MarkerSize',msize);
                    catch
                        % for empty series
                    end
                end
            catch 
            end
                            
        end
    
        function ExtractCoordinateFrame(app)
            try
                x1_ = app.X1EditField.Value;
                x2_ = app.X2EditField.Value;
                y1_ = app.Y1EditField.Value;
                y2_ = app.Y2EditField.Value;
                app.x1(app.selectedSeries) = x1_;
                app.x2(app.selectedSeries) = x2_;
                app.y1(app.selectedSeries) = y1_;
                app.y2(app.selectedSeries) = y2_;
                
                iSeries = app.selectedSeries;
                adx = app.anchorPoints(iSeries,2) - app.anchorPoints(iSeries,1);
                ady = app.anchorPoints(iSeries,4) - app.anchorPoints(iSeries,3);
                
                % horizontal axis.
                if app.logCheckBoxX.Value 
                    xFun = @(a) 10.^(log10(x1_) + log10(x2_/x1_)*(a - app.anchorPoints(iSeries,1))/adx);
                else
                    xFun = @(a) x1_ + (x2_-x1_)*(a - app.anchorPoints(iSeries,1))/adx;
                end
                
                % vertical axis
                if app.logCheckBoxY.Value 
                    yFun = @(a) 10.^(log10(y1_) + log10(y2_/y1_)*(a - app.anchorPoints(iSeries,3))/ady);
                else
                    yFun = @(a) y1_ + (y2_-y1_)*(a - app.anchorPoints(iSeries,3))/ady;
                end
                
                app.coordinateFrame{app.selectedSeries} = {xFun yFun};
            catch err
                uialert(app.DigitizerUIFigure,err.message,'ExtractCoordinateFrame error');
            end
        end


    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.X1EditField.Enable = 0;
            app.X2EditField.Enable = 0;
            app.Y1EditField.Enable = 0;
            app.Y2EditField.Enable = 0;
            app.logCheckBoxX.Enable = 0;
            app.logCheckBoxY.Enable = 0;
            app.ZoomAxes.Visible = 'off';
            app.mainGUIPosition = app.DigitizerUIFigure.Position;
        end

        % Value changed function: ChooseAnchorPointsButton
        function ChooseAnchorPointsButtonValueChanged(app, event)
            
            % first check if we have an image loaded.
            if isempty(app.im)
                uialert(app.DigitizerUIFigure, 'No image found for anchoring.', 'Missing image error');
                app.ChooseAnchorPointsButton.Value = ~app.ChooseAnchorPointsButton.Value;
                return;
            end
            
            value = app.ChooseAnchorPointsButton.Value;
            if value
                app.imHandle.HitTest = 'on';
                app.inputStatus = 1;
                set(findobj(app.ControlPanel.Children,'-not','Type','uitable'),...
                    'Enable',false);
                app.UITable.Enable = 'off';
                app.ChooseAnchorPointsButton.Enable = true;
                app.ZoomAxes.Visible = 'on';
            else
                app.imHandle.HitTest = 'off';
                app.inputStatus = 0;
                set(findobj(app.ControlPanel.Children,'-not','Type','uitable'),...
                    'Enable',true);
                app.UITable.Enable = 'on';
                app.ZoomAxes.Visible = 'off';
                cla(app.ZoomAxes);
                app.ZoomAxes.Position = [391 5 300 10];
            end
        end

        % Value changed function: AddPointsButton
        function AddPointsButtonValueChanged(app, event)
            
            if isempty(app.im)
                app.AddPointsButton.Value = ~app.AddPointsButton.Value;
                uialert(app.DigitizerUIFigure,'No image found. Please load an image first.','No image');
                return;
            end
            
            % check for anchor points
            if isempty(app.coordinateFrame)
                uialert(app.DigitizerUIFigure, 'No anchor points found.', 'Missing anchors.');
                app.AddPointsButton.Value = ~app.AddPointsButton.Value;
                return;
            elseif isempty(app.coordinateFrame{app.selectedSeries})
                uialert(app.DigitizerUIFigure, 'No anchor points found.', 'Missing anchors.');
                app.AddPointsButton.Value = ~app.AddPointsButton.Value;
                return;
            end
            
            % check if there is a series. if not, create one.
            if app.numberOfSeries == 0
                app.AddSeriesButtonPushed;
            end
                
            
            value = app.AddPointsButton.Value;
            
            if value
                app.imHandle.HitTest = 'on';
                app.inputStatus = 2;
                
                % disable other UI elements but this one
                set(findobj(app.ControlPanel.Children,'-not','Type','uitable'),...
                    'Enable',false);
                % app.UITable.Enable = 'off';
                app.AddPointsButton.Enable = true;
                app.ZoomAxes.Visible = 'on';
            else
                app.imHandle.HitTest = 'off';
                app.inputStatus = 0;
                
                % enable other UI elements
                set(findobj(app.ControlPanel.Children,'-not','Type','uitable'),...
                    'Enable',true);
                % app.UITable.Enable = 'on';
                app.ZoomAxes.Visible = 'off';
                cla(app.ZoomAxes);
                app.ZoomAxes.Position = [391 5 300 10];
            end


        end

        % Value changed function: RemovePointsButton
        function RemovePointsButtonValueChanged(app, event)
            
            if app.numberOfSeries == 0
                app.RemovePointsButton.Value = ~app.RemovePointsButton.Value;
                uialert(app.DigitizerUIFigure,'There are no points to remove!','Nothing to remove')
                return;
            end
            
            if isempty(app.data{app.selectedSeries})
                app.RemovePointsButton.Value = ~app.RemovePointsButton.Value;
                uialert(app.DigitizerUIFigure,'There are no points to remove!','Nothing to remove')
                return;
            end
            
            % first check for anchor points
            if isempty(app.coordinateFrame)
                uialert(app.DigitizerUIFigure, 'No anchor points found.', 'Missing anchors.');
                app.RemovePointsButton.Value = ~app.RemovePointsButton.Value;
                return;
                
            elseif isempty(app.coordinateFrame{app.selectedSeries})
                uialert(app.DigitizerUIFigure, 'No anchor points found.', 'Missing anchors.');
                app.AddPointsButton.Value = ~app.AddPointsButton.Value;
                return;
            end
            
            % check if there is a series. if not, return.
            if app.numberOfSeries == 0
                app.RemovePointsButton.Value = ~app.RemovePointsButton.Value;
                return;
            end
            
            value = app.RemovePointsButton.Value;
            
            if value
                app.imHandle.HitTest = 'on';
                app.inputStatus = 2;
                
                % disable other UI elements but this one
                set(findobj(app.ControlPanel.Children,'-not','Type','uitable'),...
                    'Enable',false);
                app.UITable.Enable = 'on';
                app.RemovePointsButton.Enable = true;
                
            else
                app.imHandle.HitTest = 'off';
                app.inputStatus = 0;
                
                % enable other UI elements
                set(findobj(app.ControlPanel.Children,'-not','Type','uitable'),...
                    'Enable',true);
                app.UITable.Enable = 'on';
                
            end
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
            
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Value changed function: logCheckBoxY
        function logCheckBoxYValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Value changed function: Y2EditField
        function Y2EditFieldValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Value changed function: Y1EditField
        function Y1EditFieldValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Value changed function: logCheckBoxX
        function logCheckBoxXValueChanged(app, event)
            app.ExtractCoordinateFrame;
            app.UpdateData;
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            if app.RemovePointsButton.Value
                tempData = app.realData{app.selectedSeries};
                tempData(indices(:,1),:) = [];
                app.realData{app.selectedSeries} = tempData;
                app.UITable.Data = app.realData{app.selectedSeries};
                pxData = app.data{app.selectedSeries};
                pxData(indices(:,1),:) = [];
                app.data{app.selectedSeries} = pxData;
                app.dataCounter{app.selectedSeries} = size(pxData,1);
                app.selectedDataPoint = size(app.data{app.selectedSeries},1);
                app.PlotUpdate;
            else
                app.selectedDataPoint = indices;
            end


        end

        % Menu selected function: OpenImageMenu
        function OpenImageMenuSelected(app, event)
            
%             if ~isempty(app.im)
%                 selection = uiconfirm(app.DigitizerUIFigure,...
%                     ['Loading another image will lose all extracted'...
%                     ' data and settings. Do you want to continue?'] ...
%                     ,'Confirm Close','Icon','warning','DefaultOption',2);
%                 switch selection
%                     case 'Cancel'
%                         return;
%                     otherwise
%                 end
%             end
                        
            % ask user to select an image file
            app.DigitizerUIFigure.Visible = 'off';
            [file,path] = uigetfile({'*.jpg;*.jpeg;*.png;*.pdf;*.tif;*.tiff;*.bmp'},...
                'Select an image file','MultiSelect', 'off');
            app.SelectanimagefileLabel.Text = sprintf('%s%s\n',path,file);
            app.DigitizerUIFigure.Visible = 'on';
            figure(app.DigitizerUIFigure);
  
            
            try
                if ~isempty(file) && ischar(file)
                    % check if there is already an image
                    app.im = []; % image matrix
                    app.imHandle = [];
                else
                    % user canceled file selection
                    return;
                end
                
                app.im = imread([path file]);
                if app.numberOfSeries == 0
                    app.AddSeriesButtonPushed;
                end
                cla(app.UIAxes);
                % app.imHandle = imshow(app.im, 'Border', 'tight','Parent',app.UIAxes);
                app.imHandle = imagesc(app.UIAxes,'CData',flipud(app.im));
                app.imHandle.HitTest = 'off';
                app.imHandle.ButtonDownFcn = @(~,e) app.ImButtonDownFcn(e);
                app.UITable.ColumnName = {'X','Y'};
                app.UITable.ColumnEditable = true;
                app.UITable.Data = [];
                
            catch ME
                % If problem reading image, display error message
                uialert(app.DigitizerUIFigure, ME.message, 'Image Error');
                return;
            end
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuSelected(app, event)
            
            if isempty(app.im)
                % If problem reading image, display error message
                uialert(app.DigitizerUIFigure, 'Nothing to save', 'Image Error');
                return;
            end
            
            % ask user to input a file name to
            app.DigitizerUIFigure.Visible = 'off';
            [file,path,~] = uiputfile('data.mat');
            app.DigitizerUIFigure.Visible = 'on';
            figure(app.DigitizerUIFigure);
            
            
            if ~isempty(file) && ischar(file)
                myData = [];
                for i=1:app.numberOfSeries
                    try
                        myData.(app.seriesName{i}) = app.realData{i};
                    catch
                        % probably an empty series encountered
                    end
                end
                save([path filesep file],'-struct','myData');
            else
                return;
            end
            uialert(app.DigitizerUIFigure, 'Data saved successfully!','Success!','icon','success');
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            tData = app.realData{app.selectedSeries};
            tData(indices(1,1),indices(1,2)) = newData;
            app.realData{app.selectedSeries} = tData;
        end

        % Button pushed function: AddSeriesButton
        function AddSeriesButtonPushed(app, event)
    
            if isempty(app.im)
                uialert(app.DigitizerUIFigure,'Please load an image first.','No image');
                return;
            end
            
            app.numberOfSeries = app.numberOfSeries + 1;
            app.selectedSeries = app.numberOfSeries;
            if app.numberOfSeries > 1
                app.anchorPoints(app.selectedSeries,:) = app.anchorPoints(app.selectedSeries-1,:);
                app.coordinateFrame{app.selectedSeries} = app.coordinateFrame{app.selectedSeries-1}; % use anchors from previous series
                app.x1(app.selectedSeries) = app.x1(app.selectedSeries-1);
                app.x2(app.selectedSeries) = app.x2(app.selectedSeries-1);
                app.y1(app.selectedSeries) = app.y1(app.selectedSeries-1);
                app.y2(app.selectedSeries) = app.y2(app.selectedSeries-1);
            else
                app.anchorPoints(app.selectedSeries,:) = nan(1,4);
                app.coordinateFrame{app.selectedSeries} = {};
                app.x1(app.selectedSeries) = 0;
                app.x2(app.selectedSeries) = 0;
                app.y1(app.selectedSeries) = 0;
                app.y2(app.selectedSeries) = 0;
            end
            app.seriesName{app.selectedSeries} = ['series' num2str(app.selectedSeries)];
            app.data{app.selectedSeries} = [];
            app.dataCounter{app.selectedSeries} = 0;
            app.realData{app.selectedSeries} = [];
            app.DataSeriesListBox.Items = app.seriesName;
            app.DataSeriesListBox.Value = app.seriesName{app.selectedSeries};
            
            app.X1EditField.Enable = 1;
            app.X2EditField.Enable = 1;
            app.Y1EditField.Enable = 1;
            app.Y2EditField.Enable = 1;
            app.logCheckBoxX.Enable = 1;
            app.logCheckBoxY.Enable = 1;
            
            app.ExtractCoordinateFrame;
            app.UpdateData;
            app.PlotUpdate;
            
        end

        % Button pushed function: RemoveSeriesButton
        function RemoveSeriesButtonPushed(app, event)
            
            % check if there is a series. if not, return.
            if app.numberOfSeries == 0
                uialert(app.DigitizerUIFigure,'No series to remove!','No series to remove');
                return;
            end

            app.coordinateFrame(app.selectedSeries) = [];
            app.seriesName(app.selectedSeries) = [];
            app.data(app.selectedSeries) = [];
            app.dataCounter(app.selectedSeries) = [];
            app.realData(app.selectedSeries) = [];
            app.numberOfSeries = app.numberOfSeries - 1;
            app.selectedSeries = app.numberOfSeries;
            app.DataSeriesListBox.Items = app.seriesName;
            if app.selectedSeries == 0
                app.DataSeriesListBox.Value = {};
                app.UITable.Data = [];
                app.X1EditField.Enable = 0;
                app.X2EditField.Enable = 0;
                app.Y1EditField.Enable = 0;
                app.Y2EditField.Enable = 0;
                app.logCheckBoxX.Enable = 0;
                app.logCheckBoxY.Enable = 0;
            else
                app.DataSeriesListBox.Value = app.seriesName{app.selectedSeries};
            end
            
            app.PlotUpdate;
            
        end

        % Value changed function: DataSeriesListBox
        function DataSeriesListBoxValueChanged(app, event)
            ix = find(strcmp(app.DataSeriesListBox.Items,app.DataSeriesListBox.Value));
            app.selectedSeries = ix;
            app.UITable.Data = app.realData{app.selectedSeries};
            app.X1EditField.Value = app.x1(app.selectedSeries);
            app.X2EditField.Value = app.x2(app.selectedSeries);
            app.Y1EditField.Value = app.y1(app.selectedSeries);
            app.Y2EditField.Value = app.y2(app.selectedSeries);
            app.PlotUpdate;
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            if isempty(app.DataSeriesListBox.Items)
                uialert(app.DigitizerUIFigure,'No series to rename! Please add a series first.','No series');
                return;
            end
            answer = inputdlg('Enter a data series name','Edit data series name',[1 50],{app.seriesName{app.selectedSeries}});
            if ~isempty(answer) 
                if isvarname(answer{1})
                    if any(contains(app.DataSeriesListBox.Items,answer{1}))
                        uialert(app.DigitizerUIFigure,'Series name exist. Please choose a different name.','Name exists');
                        return;
                    end
                    app.DataSeriesListBox.Items{app.selectedSeries} = answer{1};
                    app.seriesName{app.selectedSeries} = answer{1};
                    app.DataSeriesListBox.Value = answer{1};
                else
                    uialert(app.DigitizerUIFigure, 'Please enter a valid variable name.', ...
                        'Variable Name Error');
                end
            end
            figure(app.DigitizerUIFigure);
            
        end

        % Button pushed function: xButton
        function ButtonPushed(app, event)
            if ~isempty(app.im)
                selection = uiconfirm(app.DigitizerUIFigure,...
                    'All extracted data and settings will be lost. Do you want to continue?'...
                    ,'Confirm Close','Icon','warning','DefaultOption',1);
                switch selection
                    case 'Cancel'
                        return;
                    otherwise
                end
            end
            
            % clear everything
            app.im = []; % image matrix
            app.anchorPoints = nan(1,4); % anchor points | Xstart Xend Ystart Yend
            app.anchorCounter = 0;
            app.inputStatus = 0; % 0 idle, 1 anchor, 2 data
            app.selectedSeries = 0;
            app.numberOfSeries = 0;
            app.data = {[]};
            app.dataCounter = {0};
            app.imHandle = [];
            app.coordinateFrame = {};
            app.realData = {[]};
            app.seriesName = {};
            app.SelectanimagefileLabel.Text = '';
            app.x1 = []; app.x2 = []; app.y1 =[]; app.y2 = [];
            app.DataSeriesListBox.Items = {};
            app.DataSeriesListBox.Value = {};
            app.UITable.Data = [];
            app.X1EditField.Value = 0;
            app.X2EditField.Value = 0;
            app.Y1EditField.Value = 0;
            app.Y2EditField.Value = 0;
            app.logCheckBoxX.Value = 0;
            app.logCheckBoxY.Value = 0;
            
            cla(app.UIAxes);
            app.PlotUpdate;


        end

        % Size changed function: DigitizerUIFigure
        function DigitizerUIFigureSizeChanged(app, event)
            position = app.DigitizerUIFigure.Position;
            if abs(position(3) - app.mainGUIPosition(3)) > 1 || ...
                    abs(position(4) - app.mainGUIPosition(4)) > 23
                app.ControlPanel.Position = [15 15 250 position(4)-60];
                app.SelectanimagefileLabel.Position = [15 position(4)-40 position(3)-50 25];
                app.UIAxes.Position = [270 15 position(3)-280 position(4)-50];
                
                cla(app.UIAxes);
                app.imHandle = imagesc(app.UIAxes,'CData',flipud(app.im));
                app.imHandle.HitTest = 'off';
                app.imHandle.ButtonDownFcn = @(~,e) app.ImButtonDownFcn(e);
                
                app.mainGUIPosition = position;
            else
                app.mainGUIPosition(1:2) = position(1:2);
            end
        end

        % Callback function
        function DigitizerUIFigureWindowButtonMotion(app, event)
%%%% Some experimenting with dynamic zoom at the mouse cursor location. 
%%%% inspired by WebPlotDigitizer. But it does not work fast enough. So
%%%% I commented it out. Removed the link to this callback.
%
%            
%             if (app.inputStatus == 1 || app.inputStatus == 2) 
%                 position = get(app.DigitizerUIFigure, 'CurrentPoint');
%                 axOutPos = app.UIAxes.OuterPosition;
%                 axInPos = app.UIAxes.Position;
%                 base = axOutPos(1:2);
%                 if position(1)<base(1) || position(2)<base(2) || ...
%                         position(1)>sum(axOutPos([1 3])) || position(2)>sum(axOutPos([2 4]))
%                     return;
%                 end
%                 zoomAxesSize = 300;
%                 img = screencapture(app.DigitizerUIFigure,[position 50 50]);
% % %                 mainGUIPosition = app.DigitizerUIFigure.Position;
% % %                 plotAreaRect = [base mainGUIPosition(3)-base(1)-10 mainGUIPosition(4)-50];
% % %                 zoomAxesWidthHeight = plotAreaRect(3:4)/3;
% % %                 newPos = position-base+100;
% % %                 absolutePos = [rem(newPos(1),plotAreaRect(3)-zoomAxesSize)...
% % %                     rem(newPos(2),plotAreaRect(4))] + base;
% % %                 app.ZoomAxes.Position = [absolutePos zoomAxesWidthHeight];
% %                 imshow(img, 'Border', 'tight','Parent',app.ZoomAxes);
%                 imagesc(app.ZoomAxes,'CData',flipud(img));
%                 app.ZoomAxes.Position = [10 10 zoomAxesSize*[1 1]];
%                 drawnow;
%                 fprintf('%d %d\t %d %d \n',position(1),position(2),axOutPos(1),axOutPos(2));
%             end
        end

        % Close request function: DigitizerUIFigure
        function DigitizerUIFigureCloseRequest(app, event)
            drawnow;
            pause(0.25);
            delete(app)
            
        end

        % Window key press function: DigitizerUIFigure
        function DigitizerUIFigureWindowKeyPress(app, event)
            key = event.Key;
 
            if app.inputStatus == 0
                return;
            end
            
            % if in "choose anchor" mode, let the user fine-tune 
            % the location of anchors by arrow keys.
            if app.inputStatus == 1
                pos = app.anchorPoints(app.selectedSeries,app.anchorCounter + [0 2]);
                
                if strcmpi(key,'rightarrow')
                    pos(1) = pos(1)+1;
                elseif strcmpi(key,'leftarrow')
                    pos(1) = pos(1)-1;
                elseif strcmpi(key,'uparrow')
                    pos(2) = pos(2)+1;
                elseif strcmpi(key,'downarrow')
                    pos(2) = pos(2)-1;
                end
                app.anchorPoints(app.selectedSeries,app.anchorCounter + [0 2]) = pos;

            % if in "add data points" mode, let the user fine-tune 
            % the location of data points by arrow keys.    
            elseif app.inputStatus == 2
                tempSeries = app.data{app.selectedSeries};
                tempPoint = tempSeries(app.selectedDataPoint,:);
                
                if strcmpi(key,'rightarrow')
                    tempPoint(:,1) = tempPoint(:,1)+1;
                elseif strcmpi(key,'leftarrow')
                    tempPoint(:,1) = tempPoint(:,1)-1;
                elseif strcmpi(key,'uparrow')
                    tempPoint(:,2) = tempPoint(:,2)+1;
                elseif strcmpi(key,'downarrow')
                    tempPoint(:,2) = tempPoint(:,2)-1;
                end
                tempSeries(app.selectedDataPoint,:) = tempPoint;
                app.data{app.selectedSeries} = sortrows(tempSeries,1,'ascend');
            end
            
            app.UpdateData;
            app.PlotUpdate;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DigitizerUIFigure = uifigure;
            app.DigitizerUIFigure.Name = 'Digitizer';
            app.DigitizerUIFigure.CloseRequestFcn = createCallbackFcn(app, @DigitizerUIFigureCloseRequest, true);
            app.DigitizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @DigitizerUIFigureSizeChanged, true);
            app.DigitizerUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @DigitizerUIFigureWindowKeyPress, true);
            app.FileMenu = uimenu(app.DigitizerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenImageMenu = uimenu(app.FileMenu);
            app.OpenImageMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenImageMenuSelected, true);
            app.OpenImageMenu.Text = 'Open Image';
            app.SaveDataMenu = uimenu(app.FileMenu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveDataMenu.Text = 'Save Data';
            app.UIAxes = uiaxes(app.DigitizerUIFigure);
            title(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SelectanimagefileLabel = uilabel(app.DigitizerUIFigure);
            app.SelectanimagefileLabel.Text = 'Select an image file.';
            app.ControlPanel = uipanel(app.DigitizerUIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.AddPointsButton = uibutton(app.ControlPanel, 'state');
            app.AddPointsButton.ValueChangedFcn = createCallbackFcn(app, @AddPointsButtonValueChanged, true);
            app.AddPointsButton.Text = 'Add Points';
            app.RemovePointsButton = uibutton(app.ControlPanel, 'state');
            app.RemovePointsButton.ValueChangedFcn = createCallbackFcn(app, @RemovePointsButtonValueChanged, true);
            app.RemovePointsButton.Text = 'Remove Points';
            app.AddSeriesButton = uibutton(app.ControlPanel, 'push');
            app.AddSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @AddSeriesButtonPushed, true);
            app.AddSeriesButton.Text = 'Add Series';
            app.RemoveSeriesButton = uibutton(app.ControlPanel, 'push');
            app.RemoveSeriesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveSeriesButtonPushed, true);
            app.RemoveSeriesButton.Text = 'Remove Series';
            app.ChooseAnchorPointsButton = uibutton(app.ControlPanel, 'state');
            app.ChooseAnchorPointsButton.ValueChangedFcn = createCallbackFcn(app, @ChooseAnchorPointsButtonValueChanged, true);
            app.ChooseAnchorPointsButton.Tooltip = {'Select two points on the image and enter the corresponding coordinates below.'};
            app.ChooseAnchorPointsButton.Text = 'Choose Anchor Points';
            app.logCheckBoxY = uicheckbox(app.ControlPanel);
            app.logCheckBoxY.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxYValueChanged, true);
            app.logCheckBoxY.Text = 'log';
            app.logCheckBoxX = uicheckbox(app.ControlPanel);
            app.logCheckBoxX.ValueChangedFcn = createCallbackFcn(app, @logCheckBoxXValueChanged, true);
            app.logCheckBoxX.Text = 'log';
            app.X1EditFieldLabel = uilabel(app.ControlPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.ControlPanel, 'numeric');
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.X2EditFieldLabel = uilabel(app.ControlPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.ControlPanel, 'numeric');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.Y1EditFieldLabel = uilabel(app.ControlPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.ControlPanel, 'numeric');
            app.Y1EditField.ValueChangedFcn = createCallbackFcn(app, @Y1EditFieldValueChanged, true);
            app.Y2EditFieldLabel = uilabel(app.ControlPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.ControlPanel, 'numeric');
            app.Y2EditField.ValueChangedFcn = createCallbackFcn(app, @Y2EditFieldValueChanged, true);
            app.xButton = uibutton(app.ControlPanel, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.xButton.Tooltip = {'Clear all'};
            app.xButton.Text = 'x';
            app.UITable = uitable(app.ControlPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DataSeriesListBoxLabel = uilabel(app.ControlPanel);
            app.DataSeriesListBoxLabel.Text = 'Data Series';
            app.DataSeriesListBox = uilistbox(app.ControlPanel);
            app.DataSeriesListBox.Items = {};
            app.DataSeriesListBox.ValueChangedFcn = createCallbackFcn(app, @DataSeriesListBoxValueChanged, true);
            app.DataSeriesListBox.Value = {};
            app.EditButton = uibutton(app.ControlPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.ZoomAxes = uiaxes(app.DigitizerUIFigure);
            app.ZoomAxes.XTick = [];
            app.ZoomAxes.YTick = [];
            app.ZoomAxes.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Digitizer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DigitizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DigitizerUIFigure)
        end
    end
end